classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ImportItem                     matlab.ui.container.Menu
        ExportItem                     matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        Menu_2                         matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        SimulationTab                  matlab.ui.container.Tab
        TimerLabel                     matlab.ui.control.Label
        SimulationTimeEditField        slrealtime.ui.control.SimulationTimeEditField
        OptionsdesimulationPanel       matlab.ui.container.Panel
        testEditField                  matlab.ui.control.EditField
        testEditFieldLabel             matlab.ui.control.Label
        BruitlumineuxButtonGroup       matlab.ui.container.ButtonGroup
        ModrButton                     matlab.ui.control.RadioButton
        FaibleButton                   matlab.ui.control.RadioButton
        AmplitudedelasourceEditField   matlab.ui.control.NumericEditField
        AmplitudedelasourceLabel       matlab.ui.control.Label
        FrquencedelasourceEditField    matlab.ui.control.NumericEditField
        FrquencedelasourceLabel        matlab.ui.control.Label
        InsrerlecodebarresEditField    matlab.ui.control.NumericEditField
        InsrerlecodebarresLabel        matlab.ui.control.Label
        PuissancedufaisceauI_0EditField  matlab.ui.control.NumericEditField
        PuissancedufaisceauI_0Label    matlab.ui.control.Label
        ZonedeRayleighz_0EditField     matlab.ui.control.NumericEditField
        ZonedeRayleighz_0Label         matlab.ui.control.Label
        Tailledufaisceaulaserw_0EditField  matlab.ui.control.NumericEditField
        TailledufaisceaulaserLabel     matlab.ui.control.Label
        CoeffdinertiedugalvoEditField  matlab.ui.control.NumericEditField
        CoeffdinertiedugalvoEditFieldLabel  matlab.ui.control.Label
        CoeffdefrottementdugalvoEditField  matlab.ui.control.NumericEditField
        CoeffdefrottementdugalvoLabel  matlab.ui.control.Label
        DistancexducodebarrescmEditField  matlab.ui.control.NumericEditField
        DistancexducodebarrescmLabel   matlab.ui.control.Label
        DistanceyducodebarrescmEditField  matlab.ui.control.NumericEditField
        DistanceyducodebarrescmLabel   matlab.ui.control.Label
        DmarrerlasimulationButton      matlab.ui.control.Button
        TempsdesimulationsSlider       matlab.ui.control.Slider
        TempsdesimulationsSliderLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        ParamtrescodebarresTab         matlab.ui.container.Tab
        DfinitiondesanglesLabel        matlab.ui.control.Label
        ParamtresPanel                 matlab.ui.container.Panel
        Anglehorizontalducodebarres_cdegEditField  matlab.ui.control.NumericEditField
        AnglehorizontalducodebarresdegrsLabel  matlab.ui.control.Label
        RflectivitdelasurfaceSlider_2  matlab.ui.control.Slider
        RflectivitdelasurfaceSlider_2Label  matlab.ui.control.Label
        Angleverticalducodebarres_cdegEditField  matlab.ui.control.NumericEditField
        Angleverticalducodebarres_cdegLabel  matlab.ui.control.Label
        RayondecourburecmLabel         matlab.ui.control.Label
        RayondecourburecmEditField_2   matlab.ui.control.NumericEditField
        Image_3                        matlab.ui.control.Image
        VisualisationTab               matlab.ui.container.Tab
        Image_2                        matlab.ui.control.Image
        VisualisationdelasimulationPanel  matlab.ui.container.Panel
        Button4                        matlab.ui.control.Button
        ConvolutionButton              matlab.ui.control.Button
        SortieButton                   matlab.ui.control.Button
        AnglethetaButton               matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        variables = {'dx_c', 'dy_c',"freq","amp", 'alpha_c',"phi_c","courbure","BRDF", 'barres', 'inertie_galvo', 'frottement_galvo', "I_0", "z_0", "w_0"}
    end
    
    methods (Access = private)
        
        function WriteVarsToWorkspace(app,ws)
            assignin(ws, "dx_c",app.DistancexducodebarrescmEditField.Value);
            assignin(ws, "dy_c",app.DistanceyducodebarrescmEditField.Value);
            assignin(ws, "alpha_c",app.Angleverticalducodebarres_cdegEditField.Value);
            assignin(ws, "phi_c",app.Anglehorizontalducodebarres_cdegEditField.Value);
            assignin(ws, "barres",app.InsrerlecodebarresEditField.Value);
            assignin(ws, "inertie_galvo",app.CoeffdinertiedugalvoEditField.Value);
            assignin(ws, "frottement_galvo",app.CoeffdefrottementdugalvoEditField.Value);
            assignin(ws, "I_0",app.PuissancedufaisceauI_0EditField.Value);
            assignin(ws, "z_0",app.ZonedeRayleighz_0EditField.Value);
            assignin(ws, "w_0", app.Tailledufaisceaulaserw_0EditField.Value);
            assignin(ws,"BRDF", app.RflectivitdelasurfaceSlider_2.Value);
            assignin(ws,"courbure", app.RayondecourburecmEditField_2.Value);
            assignin(ws,"amp", app.AmplitudedelasourceEditField.Value);
            assignin(ws,"freq", app.FrquencedelasourceEditField.Value);
            assignin(ws, "gamer", str2double(strsplit(app.testEditField.Value,','));
        end
        
        function ReadVarsFromNamespace(app,ns)
            app.DistancexducodebarrescmEditField.Value = ns.dx_c;
            app.DistanceyducodebarrescmEditField.Value = ns.dy_c;
            app.InsrerlecodebarresEditField.Value = ns.barres;
            app.CoeffdinertiedugalvoEditField.Value = ns.inertie_galvo;
            app.CoeffdefrottementdugalvoEditField.Value = ns.frottement_galvo;
            app.PuissancedufaisceauI_0EditField.Value = ns.I_0;
            app.Tailledufaisceaulaserw_0EditField.Value = ns.w_0;
            app.ZonedeRayleighz_0EditField.Value = ns.z_0;
            app.Anglehorizontalducodebarres_cdegEditField.Value = ns.phi_c;
            app.Angleverticalducodebarres_cdegEditField.Value = ns.alpha_c;
            app.RayondecourburecmEditField_2.Value = ns.courbure;
            app.RflectivitdelasurfaceSlider_2.Value = ns.BRDF;
            app.FrquencedelasourceEditField.Value = ns.freq;
            app.AmplitudedelasourceEditField.Value = ns.amp;
         end       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DmarrerlasimulationButton
        function DmarrerlasimulationButtonPushed(app, event)
            WriteVarsToWorkspace(app, 'base');
            simOut=sim("Frame.slx", "StopTime",num2str(app.TempsdesimulationsSlider.Value));
            
            code=simOut.code;
            disp(code)
            code=transpose(code);
            for i=1:(100)
                code=[code;code(1,:)];
            end
            for i=200:300:length(code)
                if i+300 > length(code)
                    break
                end
                code1= code(:,i:i+300);
            pas=((i-200)/300)+1;
               name = ['code' num2str(pas) '.png'];
                saveas(imshow(code1),name);
            I = imread(name);
            msg = readBarcode(I);
            
            if strlength(msg)==12
                break
            end
            end
        display(msg)
        end

        % Menu selected function: ExportItem
        function ExportItemMenuSelected(app, event)
            [file,path] = uiputfile('*.mat');
            WriteVarsToWorkspace(app, 'caller');
            filepath = fullfile(path,file);
            save(filepath, app.variables{:});
        end

        % Menu selected function: ImportItem
        function ImportItemMenuSelected(app, event)
            [file,path] = uigetfile('*.mat');
            if ~isequal(file,0)
                filepath = fullfile(path,file);
                ns = load(filepath, app.variables{:});
                ReadVarsFromNamespace(app,ns);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportItem = uimenu(app.FileMenu);
            app.ImportItem.MenuSelectedFcn = createCallbackFcn(app, @ImportItemMenuSelected, true);
            app.ImportItem.Text = 'Importer un fichier de configuration';
            app.ExportItem = uimenu(app.FileMenu);
            app.ExportItem.MenuSelectedFcn = createCallbackFcn(app, @ExportItemMenuSelected, true);
            app.ExportItem.Text = 'Exporter la configuration courante';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.Image = uiimage(app.SimulationTab);
            app.Image.ImageSource = 'ULAVAL.png';
            app.TempsdesimulationsSliderLabel = uilabel(app.SimulationTab);
            app.TempsdesimulationsSliderLabel.Text = 'Temps de simulation(s)';
            app.TempsdesimulationsSlider = uislider(app.SimulationTab);
            app.TempsdesimulationsSlider.Limits = [5 100];
            app.TempsdesimulationsSlider.Value = 5;
            app.DmarrerlasimulationButton = uibutton(app.SimulationTab, 'push');
            app.DmarrerlasimulationButton.ButtonPushedFcn = createCallbackFcn(app, @DmarrerlasimulationButtonPushed, true);
            app.DmarrerlasimulationButton.Text = 'Démarrer la simulation';
            app.OptionsdesimulationPanel = uipanel(app.SimulationTab);
            app.OptionsdesimulationPanel.Title = 'Options de simulation';
            app.DistanceyducodebarrescmLabel = uilabel(app.OptionsdesimulationPanel);
            app.DistanceyducodebarrescmLabel.Text = 'Distance y du code-barres [cm] :';
            app.DistanceyducodebarrescmEditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.DistancexducodebarrescmLabel = uilabel(app.OptionsdesimulationPanel);
            app.DistancexducodebarrescmLabel.Text = 'Distance x du code-barres [cm] :';
            app.DistancexducodebarrescmEditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.CoeffdefrottementdugalvoLabel = uilabel(app.OptionsdesimulationPanel);
            app.CoeffdefrottementdugalvoLabel.Text = 'Coeff. de frottement du galvo :';
            app.CoeffdefrottementdugalvoEditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.CoeffdinertiedugalvoEditFieldLabel = uilabel(app.OptionsdesimulationPanel);
            app.CoeffdinertiedugalvoEditFieldLabel.Text = 'Coeff. d''inertie du galvo :';
            app.CoeffdinertiedugalvoEditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.TailledufaisceaulaserLabel = uilabel(app.OptionsdesimulationPanel);
            app.TailledufaisceaulaserLabel.Text = 'Taille du faisceau laser (w_0):';
            app.Tailledufaisceaulaserw_0EditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.Tailledufaisceaulaserw_0EditField.Limits = [0 1];
            app.ZonedeRayleighz_0Label = uilabel(app.OptionsdesimulationPanel);
            app.ZonedeRayleighz_0Label.Text = 'Zone de Rayleigh (z_0) :';
            app.ZonedeRayleighz_0EditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.PuissancedufaisceauI_0Label = uilabel(app.OptionsdesimulationPanel);
            app.PuissancedufaisceauI_0Label.Text = 'Puissance du faisceau (I_0) :';
            app.PuissancedufaisceauI_0EditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.InsrerlecodebarresLabel = uilabel(app.OptionsdesimulationPanel);
            app.InsrerlecodebarresLabel.Text = 'Insérer le code-barres :';
            app.InsrerlecodebarresEditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.FrquencedelasourceLabel = uilabel(app.OptionsdesimulationPanel);
            app.FrquencedelasourceLabel.Text = 'Fréquence de la source:';
            app.FrquencedelasourceEditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.AmplitudedelasourceLabel = uilabel(app.OptionsdesimulationPanel);
            app.AmplitudedelasourceLabel.Text = 'Amplitude de la source:';
            app.AmplitudedelasourceEditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.BruitlumineuxButtonGroup = uibuttongroup(app.OptionsdesimulationPanel);
            app.BruitlumineuxButtonGroup.Title = 'Bruit lumineux';
            app.FaibleButton = uiradiobutton(app.BruitlumineuxButtonGroup);
            app.FaibleButton.Text = 'Faible';
            app.FaibleButton.Value = true;
            app.ModrButton = uiradiobutton(app.BruitlumineuxButtonGroup);
            app.ModrButton.Text = 'Modéré';
            app.testEditFieldLabel = uilabel(app.OptionsdesimulationPanel);
            app.testEditFieldLabel.Text = 'test';
            app.testEditField = uieditfield(app.OptionsdesimulationPanel, 'text');
            app.testEditField.Value = '1,2,3,4,5,6,7,8,9';
            app.SimulationTimeEditField = slrealtime.ui.control.SimulationTimeEditField(app.SimulationTab);
            app.TimerLabel = uilabel(app.SimulationTab);
            app.TimerLabel.Text = 'Timer?';
            app.ParamtrescodebarresTab = uitab(app.TabGroup);
            app.ParamtrescodebarresTab.Title = 'Paramètres code-barres';
            app.Image_3 = uiimage(app.ParamtrescodebarresTab);
            app.ParamtresPanel = uipanel(app.ParamtrescodebarresTab);
            app.ParamtresPanel.Title = 'Paramètres';
            app.RayondecourburecmEditField_2 = uieditfield(app.ParamtresPanel, 'numeric');
            app.RayondecourburecmLabel = uilabel(app.ParamtresPanel);
            app.RayondecourburecmLabel.Text = 'Rayon de courbure [cm]:';
            app.Angleverticalducodebarres_cdegLabel = uilabel(app.ParamtresPanel);
            app.Angleverticalducodebarres_cdegLabel.Text = 'Angle vertical du code-barres (α_c) [deg] : ';
            app.Angleverticalducodebarres_cdegEditField = uieditfield(app.ParamtresPanel, 'numeric');
            app.RflectivitdelasurfaceSlider_2Label = uilabel(app.ParamtresPanel);
            app.RflectivitdelasurfaceSlider_2Label.Text = 'Réflectivité de la surface:';
            app.RflectivitdelasurfaceSlider_2 = uislider(app.ParamtresPanel);
            app.RflectivitdelasurfaceSlider_2.Limits = [0 1];
            app.RflectivitdelasurfaceSlider_2.Value = 0.5;
            app.AnglehorizontalducodebarresdegrsLabel = uilabel(app.ParamtresPanel);
            app.AnglehorizontalducodebarresdegrsLabel.Text = 'Angle horizontal du code-barres (θ_c) [deg] : ';
            app.Anglehorizontalducodebarres_cdegEditField = uieditfield(app.ParamtresPanel, 'numeric');
            app.DfinitiondesanglesLabel = uilabel(app.ParamtrescodebarresTab);
            app.DfinitiondesanglesLabel.Text = 'Définition des angles';
            app.VisualisationTab = uitab(app.TabGroup);
            app.VisualisationTab.Title = 'Visualisation';
            app.UIAxes = uiaxes(app.VisualisationTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.VisualisationdelasimulationPanel = uipanel(app.VisualisationTab);
            app.VisualisationdelasimulationPanel.Title = 'Visualisation de la simulation';
            app.AnglethetaButton = uibutton(app.VisualisationdelasimulationPanel, 'push');
            app.AnglethetaButton.Text = 'Angle theta';
            app.SortieButton = uibutton(app.VisualisationdelasimulationPanel, 'push');
            app.SortieButton.Text = 'Sortie';
            app.ConvolutionButton = uibutton(app.VisualisationdelasimulationPanel, 'push');
            app.ConvolutionButton.Text = 'Convolution';
            app.Button4 = uibutton(app.VisualisationdelasimulationPanel, 'push');
            app.Button4.Text = 'Button4';
            app.Image_2 = uiimage(app.VisualisationTab);
            app.Image_2.ImageSource = 'ULAVAL.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end